classdef LoRa_waveform_Gui_v5_240709 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        WaveformTab                     matlab.ui.container.Tab
        LoRaWaveformFactorsPanel        matlab.ui.container.Panel
        MessageLengthByteEditField      matlab.ui.control.NumericEditField
        MessageLengthByteEditFieldLabel  matlab.ui.control.Label
        MessageEditField                matlab.ui.control.EditField
        MessageEditFieldLabel           matlab.ui.control.Label
        DataOptimizationDropDown        matlab.ui.control.DropDown
        DataOptimizationDropDownLabel   matlab.ui.control.Label
        SpreadingFactorDropDown         matlab.ui.control.DropDown
        SpreadingFactorDropDownLabel    matlab.ui.control.Label
        BandwidthkHzDropDown            matlab.ui.control.DropDown
        BandwidthkHzDropDownLabel       matlab.ui.control.Label
        CRCDropDown                     matlab.ui.control.DropDown
        CRCDropDownLabel                matlab.ui.control.Label
        HeaderDropDown                  matlab.ui.control.DropDown
        HeaderDropDownLabel             matlab.ui.control.Label
        CodeRateDropDown                matlab.ui.control.DropDown
        CodeRateDropDownLabel           matlab.ui.control.Label
        CarrierFrequencyMHzEditField    matlab.ui.control.NumericEditField
        CarrierFrequencyMHzEditFieldLabel  matlab.ui.control.Label
        RequiredSNRdB105EditField       matlab.ui.control.NumericEditField
        RequiredSNRdB105EditFieldLabel  matlab.ui.control.Label
        RequiredEbNodB105EditField      matlab.ui.control.NumericEditField
        RequiredEbNodB105EditFieldLabel  matlab.ui.control.Label
        TimeOnAirmsEditField            matlab.ui.control.NumericEditField
        TimeOnAirmsEditFieldLabel       matlab.ui.control.Label
        ChipRatekcpsEditField           matlab.ui.control.NumericEditField
        ChipRatekcpsEditFieldLabel      matlab.ui.control.Label
        SymbolRatespsEditField          matlab.ui.control.NumericEditField
        SymbolRatespsEditFieldLabel     matlab.ui.control.Label
        SensitivitydBm6dBNFEditField    matlab.ui.control.NumericEditField
        SensitivitydBm6dBNFEditFieldLabel  matlab.ui.control.Label
        BitRatebpsEditField             matlab.ui.control.NumericEditField
        BitRatebpsEditFieldLabel        matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        CoverageTab                     matlab.ui.container.Tab
        PathlossPanel                   matlab.ui.container.Panel
        LogDistanceInterceptEditField   matlab.ui.control.NumericEditField
        LogDistanceInterceptEditFieldLabel  matlab.ui.control.Label
        LogDistanceexponentEditField    matlab.ui.control.NumericEditField
        LogDistanceexponentEditFieldLabel  matlab.ui.control.Label
        RcvAntHeightmEditField          matlab.ui.control.NumericEditField
        RcvAntHeightmEditFieldLabel     matlab.ui.control.Label
        CoveragePanel                   matlab.ui.container.Panel
        onCheckBox                      matlab.ui.control.CheckBox
        ImportSetupButton               matlab.ui.control.Button
        SaveSetupandResultsButton       matlab.ui.control.Button
        AnalyzeButton                   matlab.ui.control.Button
        ResultsPanel                    matlab.ui.container.Panel
        UITable2                        matlab.ui.control.Table
        SetupSitePanel                  matlab.ui.container.Panel
        SensitivitydBm6dBNFEditField_2  matlab.ui.control.EditField
        SensitivitydBm6dBNFEditField_2Label  matlab.ui.control.Label
        NumberofRelayEditField          matlab.ui.control.NumericEditField
        NumberofRelayEditFieldLabel     matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
    end

    
    % methods (Access = private)
    % 
    %     function results = LogDistPathloss(app,EIRP,RxAntGain,RxNF,Xg_mean,Nexpo,Dist,Freq)
    %         % Log-distance path loss mode
    %         % EIRP dBm
    %         % Ant Gain dBi
    %         % RX receiver NF dB
    %         % Dist km
    %         % Xg_mean mean value depending on channel environment
    %         % Nexpo depending on channel environment
    %         lambda = 3e8/Freq; % freq Hz
    %         % B_pathloss_intercept = 20*log10(lambda/(4*pi*1000))*-1+Xg_mean;  % depending on channel environment
    %          B_pathloss_intercept = 119.2+Xg_mean;  % depending on channel environment
    %         % results = EIRP + RxAntGain - (B_pathloss_intercept+10*Nexpo*log10(Dist))-RxNF;
    %         results = EIRP + RxAntGain - (B_pathloss_intercept+10*Nexpo*log10(Dist))-1; % cable loss is assumed as 1 dB 
    %     end
    % end

    methods (Access = private)    
        function results = RSSIcal(app,EIRP,RxAntGain,Lsys_rx,Xg_mean,Nexpo,Dist)
            % Log-distance path loss mode
            % Tx powr dBm
            % Tx and Rx Ant Gain dBi
            % Cable loss dB
            % Xg_mean mean value depending on channel environment
            % Nexpo depending on channel environment
            % Dist km
            %lambda = 3e8/Freq; % freq Hz
            % B_pathloss_intercept = 20*log10(lambda/(4*pi*1000))*-1+Xg_mean;  % depending on channel environment
             B_pathloss_intercept = app.LogDistanceInterceptEditField.Value+Xg_mean;  % depending on channel environment
            % results = EIRP + RxAntGain - (B_pathloss_intercept+10*Nexpo*log10(Dist))-RxNF;
            results = EIRP - (B_pathloss_intercept+10*Nexpo*log10(Dist)) + RxAntGain - Lsys_rx; % cable loss is assumed as 1 dB 
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.CalculateButton.Text = "in Processing";
            app.CalculateButton.BackgroundColor = 'r';
            app.CalculateButton.Interruptible = 'off';
            %% Waveform Tab initialize
            BW = str2num(app.BandwidthkHzDropDown.Value)*1000; %Hz
            %display(app.CarrierFrequencyMHzEditField.Value)
            Freq = app.CarrierFrequencyMHzEditField.Value*1000000; %Hz
            lambda = 3*1e8/Freq;
            Roll_off = 0 ;
            SF = str2num(app.SpreadingFactorDropDown.Value);
            %NF_receiver = app.ReceiverNFdBEditField.Value;
            
            Code_Rate_str = app.CodeRateDropDown.Value;
            Code_Rate = str2num(Code_Rate_str);
            CR = 1/Code_Rate*4-4;

            % Header flag define
            if strcmp(app.HeaderDropDown.Value,'On')
                Header_flag = 0; % 0 with explicit header, 1 with implicit header
            elseif strcmp(app.HeaderDropDown.Value,'Off')
                Header_flag = 1;
            end

            % Data Rate Optimization flag define
            if strcmp(app.DataOptimizationDropDown.Value,'On') || SF==11 || SF == 12
                DE_flag = 1; % 1 means Data Rate Optimization activated, always 1 for SF=11, 12
                app.DataOptimizationDropDown.Value = 'On'

            elseif strcmp(app.DataOptimizationDropDown.Value,'Off')
                DE_flag = 0;
            end

            % CRC flag define
            if strcmp(app.CRCDropDown.Value,'On')
                CRC_flag = 1; 
            elseif strcmp(app.CRCDropDown.Value,'Off')
                CRC_flag = 0;
            end                        
            Nbit_CRC = 16; % bit
            
                       
            Symbol_Rate = 1/(2^SF/(BW));
            Symbol_period = 1/Symbol_Rate; % msec
            Chip_Rate = 2^SF*Symbol_Rate;
            Bit_Rate = SF * Symbol_Rate*Code_Rate;
            Bit_Rate_NoCR = SF * Symbol_Rate; % Bit Energy does't depend on Code_Rate

            % %% Time On Air Calculation
            Nsymbol_preamble = 8; % number of preamble in packet
            
            % message length
            MacHeaderByte = 1;
            MICByte = 4;
            MacFrameHeaderByte = 7; %
            MacFramePortByte = 1;
            MacFramePayloadByte = app.MessageLengthByteEditField.Value ;% byte
            Nbyte_payload = MacHeaderByte + MacFrameHeaderByte + MacFramePayloadByte ...
                + MacFramePortByte + MICByte ;

            if SF == 5 || SF == 6
                Nsymbol_preamble_mod = Nsymbol_preamble + 6.25 ;
            else
                Nsymbol_preamble_mod = Nsymbol_preamble + 4.25 ;
            end
            
          
                Nsymbol_head_payload = 8 ...
                                  + ceil((8*Nbyte_payload + Nbit_CRC*CRC_flag - 4*SF + 8 + 20*(1-Header_flag))/(4*(SF-2*DE_flag)))...
                                  *(CR+4);
            
            % TimeOnAir_preamble = (Nsymbol_preamble+4.25)*1/Symbol_Rate; % Preamble duration
            Preamble_mod_period = Nsymbol_preamble_mod*Symbol_period*1000;
            Nsymbol_head_payload;
            Nsymbol_head_payload_period = Nsymbol_head_payload*Symbol_period*1000;
            TimeOnAir_packet = Preamble_mod_period + Nsymbol_head_payload_period;  %  Payload and header duration
            
            %% Sytyem Parameters, Waveform Parameters, Time On Air Display
            app.ChipRatekcpsEditField.Value = Chip_Rate/1000;
            app.SymbolRatespsEditField.Value = Symbol_Rate;
            app.BitRatebpsEditField.Value = Bit_Rate;
            app.TimeOnAirmsEditField.Value = TimeOnAir_packet;
            % simulation_param = sprintf('System Parameter [ Bandwidth: %d kHz, Frequency: %d MHz, Spreading Factor: %d, Code Rate: %s ]',BW/1000,Freq/1000000,SF,Code_Rate_str);
            % disp(simulation_param)
            % simulation_waveform = sprintf('Waveform [ Symbol Rate: %0.2f sps, Chip Rate: %d kHz, Bit Rate: %0.2f bps ]',Symbol_Rate, Chip_Rate/1000,Bit_Rate);
            % disp(simulation_waveform)

            %% BER Graph Plot
            SNR_var = -35:0.1:10; %dB scale
            Eb_over_No_dB_test = SNR_var -10*log10(Bit_Rate_NoCR/BW);
            Eb_over_No_test = 10.^(Eb_over_No_dB_test/10);
            %Prob_error_CSS_origin = qfunc((log10(SF)/log10(12))/sqrt(2)*Eb_over_No_test);
            m_SF=2^SF;
            SNR_var_real=10.^(SNR_var/10);
            Hm = log(m_SF)+1/(2*m_SF)+0.57722
            Hm_1 = log(m_SF-1)+1/(2*(m_SF-1))+0.57722
            Var_snr=(sqrt(SNR_var_real*m_SF)-(Hm_1^2-pi^2/12)^0.25)/(sqrt(Hm_1-(Hm_1^2-pi^2/12)^0.5+0.5));
            Prob_error_CSS_origin_EL = 1/2*qfunc(Var_snr);
            
            
            %% Find SNR,Eb/No @ 10^-5 BER
            Temp_10N5=10^-5-Prob_error_CSS_origin_EL;
            
            [temp min_index]=min(abs(Temp_10N5));
            if Temp_10N5(1,min_index) < 0
            min_index = min_index+1;    
            end
            %SNR_10N5=SNR_var(1,min_index);
            %Prob_error_CSS_origin_10n5 = qfunc((log10(SF)/log10(12))/sqrt(2)*Eb_over_No_test(1,min_index));
            Prob_error_CSS_origin_EL_10n5 = 1/2*qfunc(Var_snr(1,min_index));
            
            BER_curve = semilogy(app.UIAxes, SNR_var, Prob_error_CSS_origin_EL,'-', ...
                                 SNR_var(1,min_index),Prob_error_CSS_origin_EL_10n5,'o');  
            %[t,s] = title('2016 Reynder paper applied to Mroue paper','SF=11','Color','blue');
            
            app.UIAxes.XLim=[-35 0];
            app.UIAxes.YLim=[10^-6 1];


            app.RequiredEbNodB105EditField.Value = Eb_over_No_dB_test(1,min_index);
            app.RequiredSNRdB105EditField.Value = SNR_var(1,min_index);
            

            L_impl = 0.5; % implementation margin dB
            Sensitivity_6dBNF_LoRa = -174 + 10*log10(BW) + 6+ SNR_var(1,min_index) + L_impl ;% for 10^-5 BER NF=6dB assumed
            app.SensitivitydBm6dBNFEditField.Value = Sensitivity_6dBNF_LoRa;
            app.SensitivitydBm6dBNFEditField_2.Value = num2str(Sensitivity_6dBNF_LoRa);

            %% Coverage Tab initialize - Setup
            fq = app.CarrierFrequencyMHzEditField.Value*1e6;
            AntHeightforCoverage = app.RcvAntHeightmEditField.Value;

            value = 0;
            Site = {'Tx site';'Rx site'};
            Temp_lati = [36.594583; 36.756782];
            Temp_long = [-121.875427; -121.503459];
            Temp_txpo = zeros(value+2,1)+20; % default Pt 20 dBm
            Temp_closs = zeros(value+2,1)+0.5; % cable loss 0.5 dB
            Temp_anhe = zeros(value+2,1)+3; % default 3 m
            Temp_gain = zeros(value+2,1)+2; % default 2 dB,  need to be substracted
            
            Temp_name = {'NPS';'Fremont Peak'};

            %Temp_anty = 'isotropic'; %default, depending on antenna gain, add the difference between gains.
            Temp_anty = design(dipole,fq); %default, depending on antenna gain, add the difference between gains.

            Temp_Table = table(Site,Temp_name,Temp_lati,Temp_long,Temp_txpo,Temp_closs,Temp_anhe,Temp_gain);
            app.UITable.Data = Temp_Table;

            tx = txsite("Name",Temp_name(1,1), ...
                "Latitude",Temp_lati(1,1), ...
                "Longitude",Temp_long(1,1), ...
                "Antenna", Temp_anty,...
                "AntennaHeight", Temp_anhe(1,1),...
                "TransmitterPower",10^((Temp_txpo(1,1)-30)/10), ...
                "TransmitterFrequency",fq);
                % the unit of TransmitterPower for txsite is Watt (240620)

            rx = rxsite("Name",Temp_name(2,1), ...
                "Latitude",Temp_lati(2,1), ...
                "Longitude",Temp_long(2,1), ...
                "Antenna", Temp_anty,...
                "AntennaHeight", Temp_anhe(2,1),...
                "SystemLoss",Temp_closs(2,1));
            
            
            viewer = siteviewer("Name","Initial Viewer","Position",[700 90 800 600],"Terrain","gmted2010");
            show(tx)
            pattern(tx,fq);
            show(rx)
            pattern(rx,fq);
   %% Coverage Tab initialize - Result
            Region= {'Tx site > Rx site'};
            Temp_Dist = zeros(value+1,1);
            Temp_Rssi = zeros(value+1,1);
            Temp_LOS = zeros(value+1,1);

            los(tx,rx) ;

            visible = los(tx,rx) ;
            if visible == 1
               Temp_LOS = {'Visible'};
               Xg = 0; % mean value depending on channel environment
            else
               Temp_LOS = {'Non Visible'};
               Xg = 20; % mean value depending on channel environment from the measurment(03/14/2024)
            end

            %% Log-distance path loss mode
            % path loss cal.
            % pm = propagationModel("tirem");
            % Temp_Rssi = sigstrength(rx,tx,pm);
            Temp_Dist = distance(tx,rx)/1000; % km


            %B_pathloss_intercept = 20*log10(lambda/(4*pi*1000))*-1+Xg;  % depending on channel environment
            N_pathloss_exp = app.LogDistanceexponentEditField.Value; % depending on channel environment
             %Temp_Rssi = Temp_txpo(1,1) + Temp_gain(2,1) - (B_pathloss_intercept+10*N_pathloss_exp*log10(Temp_Dist))-Temp_closs(2,1);
            Tx_EIRP = Temp_txpo(1,1)+Temp_gain(1,1)-Temp_closs(1,1) ; % dBm cable loss is assumed as 0.5 dB
            Temp_Rssi = RSSIcal(app,Tx_EIRP,Temp_gain(2,1),Temp_closs(2,1), Xg, N_pathloss_exp, Temp_Dist);
            %results = RSSIcal(app,Tx_po,TxAntGain,RxAntGain,Lsys,Xg_mean,Nexpo,Dist)

            if Temp_Rssi >= Sensitivity_6dBNF_LoRa
                Temp_Stat = {'Link On'};
            elseif Temp_Rssi <= Sensitivity_6dBNF_LoRa
                Temp_Stat = {'Link Off'};
            else
                Temp_Stat = {'N/A'};                
            end

            Temp_Table2 = table(Region,Temp_Dist/1.609344,Temp_Rssi,Temp_LOS,Temp_Stat);
            app.UITable2.Data = Temp_Table2;

            % %% Case#1 Coverage function start
            %      if app.onCheckBox.Value == 1
            %             if app.NumberofRelayEditField.Value < 2
            % 
            %             weatherpm = propagationModel("gas") + propagationModel("rain");
            %             pmEstimated = propagationModel("longley-rice") + weatherpm; 
            % 
            %             % Compute receiver gain from peak antenna gain and system loss
            %             Gideal = pattern(design(dipole,fq), fq); % ideal 
            %             rxGainEstimated = max(Gideal(:)) - app.UITable.Data{1,6}; 
            % 
            %             [~,TempIndSite]=max([elevation(tx) elevation(rx)]);% 1 or 2
            % 
            %                     txCoverage = txsite("Name",app.UITable.Data{TempIndSite,2}, ...
            %                     "Latitude",app.UITable.Data{TempIndSite,3}, ...
            %                     "Longitude",app.UITable.Data{TempIndSite,4}, ...
            %                     "Antenna", Temp_anty,...
            %                     "AntennaHeight", app.UITable.Data{TempIndSite,7},...
            %                     "TransmitterPower",10^((app.UITable.Data{TempIndSite,5}-30-21)/10), ...
            %                     "TransmitterFrequency",1e9);
            %                     % the unit of TransmitterPower for txsite is Watt (240620)
            %                     % -21 is a calibration factor for the gap between the path loss model of experiment and the longley-rice model from experiments 
            %                     % refer to CoverageTest240523.m
            % 
            %                     coverage(txCoverage, ...
            %                         'PropagationModel',pmEstimated, ...
            %                         'ReceiverGain',rxGainEstimated, ...
            %                         'ReceiverAntennaHeight',AntHeightforCoverage, ...
            %                         'MaxRange',200000,...
            %                         'ColorLimits',[-137, -80],...
            %                         'SignalStrengths',-140:5:-90)
            %                     % This converage is only for SF12, Receiver Ant hegight 6 m, Transmitter power 20 dBm 
            %             end
            %      end
            %   %% Case#1 Coverage function end

               app.CalculateButton.BackgroundColor = 'w';
               app.CalculateButton.Interruptible = 'on';
               app.CalculateButton.Text = 'Calculate';
        end

        % Value changed function: NumberofRelayEditField
        function NumberofRelayEditFieldValueChanged(app, event)
            
            value = app.NumberofRelayEditField.Value;
            Site(1,1)={'Tx site'};
            Site(value+2,1)={'Rx site'}
            Site_name = "Relay%d";
            if value+2 == 2
            else
            for j= 2:(value+2-1)
                Site(j,1)={sprintf(Site_name,j-1)};
            end
            end

            % Temp_lati = zeros(value+2,1);
            % Temp_long = zeros(value+2,1);
            % Temp_txpo = zeros(value+2,1)+14; % default 14 dBm
            % Temp_closs = zeros(value+2,1)+2;  % default 1 dB
            % Temp_anhe = zeros(value+2,1)+3;   % default 3 m
            % Temp_antg= zeros(value+2,1)+0;
            % 
            % i =0;
            % while value+2 ~= i
            %     Temp_name(i+1,1) = {'N/A'};
            %     i=i+1;
            % end
            i =0;
            [Lrow, ~] = size(app.UITable.Data);
            while value+2 ~= i
                if i+1 <= Lrow
                    Temp_name(i+1,1) = app.UITable.Data{i+1,2};                
                    Temp_lati(i+1,1) = app.UITable.Data{i+1,3};
                    Temp_long(i+1,1) = app.UITable.Data{i+1,4};
                    Temp_txpo(i+1,1) = app.UITable.Data{i+1,5}; % default 14 dBm
                    Temp_closs(i+1,1) = app.UITable.Data{i+1,6}; % default 6 dB
                    Temp_anhe(i+1,1) = app.UITable.Data{i+1,7};   % default 3 m
                    Temp_antg(i+1,1) = app.UITable.Data{i+1,8};
                else
                    Temp_name(i+1,1) = {'N/A'};
                    Temp_lati(i+1,1) = 0;
                    Temp_long(i+1,1) = 0;
                    Temp_txpo(i+1,1) = 20; % default 20 dBm, antenna gain 6
                    Temp_closs(i+1,1) = 1; % default 1 dB
                    Temp_anhe(i+1,1) = 3;   % default 3 m
                    Temp_antg(i+1,1) = 6;
                end
                i=i+1;
            end

            Temp_Table = table(Site,Temp_name,Temp_lati,Temp_long,Temp_txpo,Temp_closs,Temp_anhe,Temp_antg);
            app.UITable.Data = Temp_Table;

            %% Results table
            for i = 2:value+2
               Region(i-1,1) = {sprintf("%s > %s", Site{i-1,1}, Site{i,1})};
            end
            Temp_Dist = zeros(value+1,1);
            Temp_Rssi = zeros(value+1,1);

            i =0;
            while value+1 ~= i
                Temp_LOS(i+1,1) = {'N/A'};
                Temp_Stat(i+1,1) = {'N/A'};
                i=i+1;
            end

            Temp_Table2 = table(Region,Temp_Dist,Temp_Rssi,Temp_LOS,Temp_Stat);
            app.UITable2.Data = Temp_Table2;

            app.AnalyzeButton.BackgroundColor = 'y'
        end

        % Value changing function: MessageEditField
        function MessageEditFieldValueChanging(app, event)
            changingValue = event.Value;
            value_length = length(changingValue);
            app.MessageLengthByteEditField.Value = value_length;
            app.CalculateButton.BackgroundColor = 'y';
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            clc;
            BW = str2num(app.BandwidthkHzDropDown.Value)*1000; %Hz
            %display(app.CarrierFrequencyMHzEditField.Value)
            Freq = app.CarrierFrequencyMHzEditField.Value*1000000; %Hz
            lambda = 3*1e8/Freq;
            Roll_off = 0 ;
            SF = str2num(app.SpreadingFactorDropDown.Value);
            %NF_receiver = app.ReceiverNFdBEditField.Value;
            
            Code_Rate_str = app.CodeRateDropDown.Value;
            Code_Rate = str2num(Code_Rate_str);
            CR = 1/Code_Rate*4-4;

            % Header flag define
            if strcmp(app.HeaderDropDown.Value,'On')
                Header_flag = 0; % 0 with explicit header, 1 with implicit header
            elseif strcmp(app.HeaderDropDown.Value,'Off')
                Header_flag = 1;
            end

            % Data Rate Optimization flag define
            if strcmp(app.DataOptimizationDropDown.Value,'On') || SF==11 || SF == 12
                DE_flag = 1; % 1 means Data Rate Optimization activated, always 1 for SF=11, 12
                app.DataOptimizationDropDown.Value = 'On'

            elseif strcmp(app.DataOptimizationDropDown.Value,'Off')
                DE_flag = 0;
            end

            % CRC flag define
            if strcmp(app.CRCDropDown.Value,'On')
                CRC_flag = 1; 
            elseif strcmp(app.CRCDropDown.Value,'Off')
                CRC_flag = 0;
            end                        
            Nbit_CRC = 16; % bit
            
                       
            Symbol_Rate = 1/(2^SF/(BW));
            Symbol_period = 1/Symbol_Rate; % msec
            Chip_Rate = 2^SF*Symbol_Rate;
            Bit_Rate = SF * Symbol_Rate*Code_Rate;
            Bit_Rate_NoCR = SF * Symbol_Rate; % Bit Energy does't depend on Code_Rate

            % %% Time On Air Calculation
            Nsymbol_preamble = 8; % number of preamble in packet
            
            % message length
            MacHeaderByte = 1;
            MICByte = 4;
            MacFrameHeaderByte = 7; % it's the minimum byte without Frame option
            MacFramePortByte = 1;
            MacFramePayloadByte = app.MessageLengthByteEditField.Value ;% byte
            Nbyte_payload = MacHeaderByte + MacFrameHeaderByte + MacFramePayloadByte ...
                + MacFramePortByte + MICByte ;

            if SF == 5 || SF == 6
                Nsymbol_preamble_mod = Nsymbol_preamble + 6.25 ;
            else
                Nsymbol_preamble_mod = Nsymbol_preamble + 4.25 ;
            end
            
          
                Nsymbol_head_payload = 8 ...
                                  + ceil((8*Nbyte_payload + Nbit_CRC*CRC_flag - 4*SF + 8 + 20*(1-Header_flag))/(4*(SF-2*DE_flag)))...
                                  *(CR+4);
            
            % TimeOnAir_preamble = (Nsymbol_preamble+4.25)*1/Symbol_Rate; % Preamble duration
            Preamble_mod_period = Nsymbol_preamble_mod*Symbol_period*1000;
            Nsymbol_head_payload;
            Nsymbol_head_payload_period = Nsymbol_head_payload*Symbol_period*1000;
            TimeOnAir_packet = Preamble_mod_period + Nsymbol_head_payload_period;  %  Payload and header duration
            
            %% Sytyem Parameters, Waveform Parameters, Time On Air Display
            app.ChipRatekcpsEditField.Value = Chip_Rate/1000;
            app.SymbolRatespsEditField.Value = Symbol_Rate;
            app.BitRatebpsEditField.Value = Bit_Rate;
            app.TimeOnAirmsEditField.Value = TimeOnAir_packet;
            % simulation_param = sprintf('System Parameter [ Bandwidth: %d kHz, Frequency: %d MHz, Spreading Factor: %d, Code Rate: %s ]',BW/1000,Freq/1000000,SF,Code_Rate_str);
            % disp(simulation_param)
            % simulation_waveform = sprintf('Waveform [ Symbol Rate: %0.2f sps, Chip Rate: %d kHz, Bit Rate: %0.2f bps ]',Symbol_Rate, Chip_Rate/1000,Bit_Rate);
            % disp(simulation_waveform)

            %% BER Graph Plot
            SNR_var = -35:0.1:10; %dB scale
            Eb_over_No_dB_test = SNR_var -10*log10(Bit_Rate_NoCR/BW);
            Eb_over_No_test = 10.^(Eb_over_No_dB_test/10);
            %Prob_error_CSS_origin = qfunc((log10(SF)/log10(12))/sqrt(2)*Eb_over_No_test);
            m_SF=2^SF;
            SNR_var_real=10.^(SNR_var/10);
            Hm = log(m_SF)+1/(2*m_SF)+0.57722
            Hm_1 = log(m_SF-1)+1/(2*(m_SF-1))+0.57722
            Var_snr=(sqrt(SNR_var_real*m_SF)-(Hm_1^2-pi^2/12)^0.25)/(sqrt(Hm_1-(Hm_1^2-pi^2/12)^0.5+0.5));
            Prob_error_CSS_origin_EL = 1/2*qfunc(Var_snr);
            
            %% Find SNR,Eb/No @ 10^-5 BER
            Temp_10N5=10^-5-Prob_error_CSS_origin_EL;
            
            [temp min_index]=min(abs(Temp_10N5));
            if Temp_10N5(1,min_index) < 0
            min_index = min_index+1;    
            end
            %SNR_10N5=SNR_var(1,min_index);
            %Prob_error_CSS_origin_10n5 = qfunc((log10(SF)/log10(12))/sqrt(2)*Eb_over_No_test(1,min_index));
            Prob_error_CSS_origin_EL_10n5 = 1/2*qfunc(Var_snr(1,min_index));
            
            BER_curve = semilogy(app.UIAxes, SNR_var, Prob_error_CSS_origin_EL,'-', ...
                                 SNR_var(1,min_index),Prob_error_CSS_origin_EL_10n5,'o');  
            %[t,s] = title('2016 Reynder paper applied to Mroue paper','SF=11','Color','blue');
            
            app.UIAxes.XLim=[-35 0];
            app.UIAxes.YLim=[10^-6 1];


            app.RequiredEbNodB105EditField.Value = Eb_over_No_dB_test(1,min_index);
            app.RequiredSNRdB105EditField.Value = SNR_var(1,min_index);

            %% Range of distance
            % EIRP_TX = app.EIRPdBmEditField.Value %dBm
            % Ant_Gain_Rx = app.ReceiverAntGaindBiEditField.Value; %dBi, monopole
            % Environment = app.AttenModelDropDown.Value;
            % 
            % Sensitivity_LoRa = -174 + 10*log10(BW) + NF_receiver + SNR_var(1,min_index) + L_impl ;% for 10^-5 BER
            L_impl = 0.5; % implementation margin dB
            Sensitivity_6dBNF_LoRa = -174 + 10*log10(BW) + 6+ SNR_var(1,min_index) + L_impl ;% for 10^-5 BER NF=6dB assumed
            app.SensitivitydBm6dBNFEditField.Value = Sensitivity_6dBNF_LoRa;
            app.SensitivitydBm6dBNFEditField_2.Value = num2str(Sensitivity_6dBNF_LoRa);
            % app.SensitivitydBm6dBNFEditField.Value = Sensitivity_LoRa ;

            app.CalculateButton.BackgroundColor = 'w';
            app.AnalyzeButton.BackgroundColor = 'y'

            %% channel model is Log-distance path loss model
            % 
            % switch Environment
            %     case 'Ground' 
            %         %B_pathloss_intercept = 128.95;
            %         B_pathloss_intercept = 20*log10(lambda/(4*pi*1000))*-1+15 % depend on channel state
            %         N_pathloss_exp = 2.3; % depend on channel state
            %         range_start = 0.1 ;
            %         range_step = 0.1 ;
            %         range_end = 200 ;
            % 
            %     case 'Space'
            %         B_pathloss_intercept = 20*log10(lambda/(4*pi*1000))*-1
            %         N_pathloss_exp = 2;
            %         range_start = 50 ;
            %         range_step = 0.5 ;
            %         range_end = 1000 ;
            % end
            % 
            %     RangeOfkm = range_start:range_step:range_end;
            %     Temp_Sensitivity_LoRa_com = EIRP_TX + Ant_Gain_Rx -...
            %     (B_pathloss_intercept+10*N_pathloss_exp*log10(RangeOfkm))-Sensitivity_LoRa;
            %     [Min_value_abs, M_index]=min(abs(Temp_Sensitivity_LoRa_com));
            % 
            %     if Temp_Sensitivity_LoRa_com(M_index) < 0
            %         app.CommunicationRangekmEditField.Value = RangeOfkm(M_index-1);
            %     else
            %         app.CommunicationRangekmEditField.Value = RangeOfkm(M_index);
            %     end
        end

        % Callback function: AnalyzeButton, UIAxes
        function AnalyzeButtonPushed(app, event)
                    
                app.AnalyzeButton.Text = "Analyzing...";
                app.AnalyzeButton.BackgroundColor = 'y';
                app.AnalyzeButton.Interruptible = 'off';

                    fq = app.CarrierFrequencyMHzEditField.Value*1e6;
                    %Temp_anty = 'isotropic'; %default, depending on antenna gain, add the difference between gains.
                    Temp_anty = design(dipole,fq);  %default, depending on antenna gain, add the difference between gains.
                    Row_Num = app.NumberofRelayEditField.Value+2;
                    Sensitivity_6dBNF_LoRa = str2num(app.SensitivitydBm6dBNFEditField_2.Value);
                    AntHeightforCoverage = app.RcvAntHeightmEditField.Value;
                    %CoverageForTx = app.UITable.D
                    
                    if app.onCheckBox.Value == 0
                        viewer_name = sprintf('Viewer for #%d Relay' ,Row_Num-2);
                    else
                        if app.NumberofRelayEditField.Value == 0
                           TempTxsite1 = txsite('Name','','Latitude',app.UITable.Data{1,3},'Longitude',app.UITable.Data{1,4});% Only site variables are available for the input of elevation
                           TempTxsite2 = txsite('Name','','Latitude',app.UITable.Data{2,3},'Longitude',app.UITable.Data{2,4});

                           TempTotalElevation1 = elevation(TempTxsite1) + app.UITable.Data{1,7}; % Surface elevation + Ant Height
                           TempTotalElevation2 = elevation(TempTxsite2) + app.UITable.Data{2,7}; % Surface elevation + Ant Height

                            [~,TempTxIndex] = max([TempTotalElevation1 TempTotalElevation2]);

                           TempTxSite = app.UITable.Data{TempTxIndex,2}; % TempTxSite is Cell type
                           viewer_name = sprintf('Viewer for #%d Relay and Covergage for a receiving antenna of %d m from %s ' ,...
                               Row_Num-2, AntHeightforCoverage, TempTxSite{1,1});% Celltype is changed into string

                        elseif app.NumberofRelayEditField.Value == 1
                           TempTxsite1 = txsite('Name','','Latitude',app.UITable.Data{1,3},'Longitude',app.UITable.Data{1,4});% Only site variables are available for the input of elevation
                           TempTxsite2 = txsite('Name','','Latitude',app.UITable.Data{2,3},'Longitude',app.UITable.Data{2,4});
                           TempTxsite3 = txsite('Name','','Latitude',app.UITable.Data{3,3},'Longitude',app.UITable.Data{3,4});

                           TempTotalElevation1 = elevation(TempTxsite1) + app.UITable.Data{1,7}; % Surface elevation + Ant Height
                           TempTotalElevation2 = elevation(TempTxsite2) + app.UITable.Data{2,7}; % Surface elevation + Ant Height
                           TempTotalElevation3 = elevation(TempTxsite3) + app.UITable.Data{3,7}; % Surface elevation + Ant Height

                           [~,TempTxIndex] = max([TempTotalElevation1 TempTotalElevation2 TempTotalElevation3]);
                           TempTxSite = app.UITable.Data{TempTxIndex,2}; % TempTxSite is Cell type
                           viewer_name = sprintf('Viewer for #%d Relay and Covergage for a receiving antenna of %d m from %s ' ,...
                               Row_Num-2, AntHeightforCoverage, TempTxSite{1,1});% Celltype is changed into string
                        end
                    end
        
                    viewer= siteviewer("Name",viewer_name,"Position",[700 90 800 600],"Terrain","gmted2010");

                    for i = 1 : Row_Num-1
                        tx = txsite("Name",app.UITable.Data{i,2}, ...
                        "Latitude",app.UITable.Data{i,3}, ...
                        "Longitude",app.UITable.Data{i,4}, ...
                        "Antenna", Temp_anty,...
                        "AntennaHeight", app.UITable.Data{i,7},...
                        "TransmitterPower",10^((app.UITable.Data{i,5}-30)/10), ...
                        "TransmitterFrequency",fq);
                        % the unit of TransmitterPower for txsite is Watt (240620)
        
                         rx = rxsite("Name",app.UITable.Data{i+1,2}, ...
                        "Latitude",app.UITable.Data{i+1,3}, ...
                        "Longitude",app.UITable.Data{i+1,4}, ...
                        "Antenna", Temp_anty,...
                        "AntennaHeight", app.UITable.Data{i+1,7},...
                        "SystemLoss",app.UITable.Data{i+1,6});
        
                        show(tx)
                        pattern(tx,fq);
                        show(rx)
                        pattern(rx,fq);
               
                        los(tx,rx) ;
        
                        visible = los(tx,rx) ;
                        if visible == 1
                           app.UITable2.Data(i,4)= {'Visible'};
                           Xg = 0; 
                        else
                           app.UITable2.Data(i,4)= {'Non Visible'};
                           %Xg = app.LogDistanceInterceptEditField.Value;
                           Xg = 38; 
                        end

                        %% Path loss calculaton start
                        % pm = propagationModel("longley-rice");
                        Temp_Dist = distance(tx,rx)/1000 ;
                        app.UITable2.Data{i,2} = Temp_Dist/1.609344; % unit mile
                        % Temp_Rssi = sigstrength(rx,tx,pm);
                        
           
                        % log distance path-loss model
                        % Xg mean value depending on channel environment
                        N_pathloss_exp = app.LogDistanceexponentEditField.Value; % depending on channel environment
                        %Temp_Rssi = Temp_txpo(1,1) + Temp_gain(2,1) - (B_pathloss_intercept+10*N_pathloss_exp*log10(Temp_Dist))-Temp_closs(2,1);
                        Lsys = app.UITable.Data{i+1,6}; % loss between antenna and Lopy4 input
                        Tx_EIRP = app.UITable.Data{i,5}+app.UITable.Data{i,8} -Lsys; % dBm
                        
                        RxAntGain = app.UITable.Data{i+1,8};
                        
                        %Temp_Rssi = LogDistPathloss(app,Tx_EIRP,RxAntGain,RxNF, Xg, N_pathloss_exp, Temp_Dist, fq);
                        %Temp_Rssi = Tx_EIRP-LogDistPathloss(app,Tx_EIRP,RxAntGain,RxNF, Xg, N_pathloss_exp, Temp_Dist, fq)+RxAntGain-1; % dBm, cable loss is assumed as 1 dB
                        %LogDistPathloss(EIRP,RxAntGain,RxNF,Xg_mean,Nexpo,Dist,Freq)
                        Temp_Rssi = RSSIcal(app,Tx_EIRP,RxAntGain,Lsys, Xg, N_pathloss_exp, Temp_Dist);
                        %results = RSSIcal(app,EIRP,RxAntGain,Lsys,Xg_mean,Nexpo,Dist)
                        %% Path loss calculaton end

                        app.UITable2.Data{i,3} = Temp_Rssi;


                        if app.UITable2.Data{i,3} >= Sensitivity_6dBNF_LoRa
                            app.UITable2.Data(i,5) = {'Link On'};
                        elseif app.UITable2.Data{i,3} <= Sensitivity_6dBNF_LoRa
                            app.UITable2.Data(i,5)= {'Link Off'};
                        else
                            app.UITable2.Data(i,5) = {'N/A'};                
                        end
                    end

                        %% Case#1 Coverage function start
                    if app.onCheckBox.Value == 1
                        if app.NumberofRelayEditField.Value == 0
                            
                        weatherpm = propagationModel("gas") + propagationModel("rain");
                        pmEstimated = propagationModel("longley-rice") + weatherpm; 
                              
                        % Compute receiver gain from peak antenna gain and system loss
                        Gideal = pattern(design(dipole,fq), fq); % ideal 
                        rxGainEstimated = max(Gideal(:)) - app.UITable.Data{1,6}; 

                        %[~,TempIndSite]=max([elevation(tx) elevation(rx)]);% 1 or 2

                                txCoverage = txsite("Name",app.UITable.Data{TempTxIndex,2}, ...
                                "Latitude",app.UITable.Data{TempTxIndex,3}, ...
                                "Longitude",app.UITable.Data{TempTxIndex,4}, ...
                                "Antenna", Temp_anty,...
                                "AntennaHeight", app.UITable.Data{TempTxIndex,7},...
                                "TransmitterPower",10^((app.UITable.Data{TempTxIndex,5}-30-21)/10), ...
                                "TransmitterFrequency",1e9);
                                % the unit of TransmitterPower for txsite is Watt (240620)
                                % -21 is a calibration factor for the gap between the path loss model of experiment and the longley-rice model from experiments 
                                % refer to CoverageTest240523.m

                                coverage(txCoverage, ...
                                    'PropagationModel',pmEstimated, ...
                                    'ReceiverGain',rxGainEstimated, ...
                                    'ReceiverAntennaHeight',AntHeightforCoverage, ...
                                    'MaxRange',200000,...
                                    'ColorLimits',[-137, -80],...
                                    'SignalStrengths',-140:5:-90)
                           % This converage is only for SF12, Receiver Ant hegight 6 m, Transmitter power 20 dBm 
                        end

                    %% Coverage function end      
                    %% Case#2 Coverage function start
                        if app.NumberofRelayEditField.Value == 1
                        weatherpm = propagationModel("gas") + propagationModel("rain");
                        pmEstimated = propagationModel("longley-rice") + weatherpm; 
                              
                        % Compute receiver gain from peak antenna gain and system loss
                        Gideal = pattern(design(dipole,fq), fq); % ideal 
                        rxGainEstimated = max(Gideal(:)) - app.UITable.Data{1,6}; 

                        txCoverage = txsite("Name",app.UITable.Data{TempTxIndex,2}, ...
                        "Latitude",app.UITable.Data{TempTxIndex,3}, ...
                        "Longitude",app.UITable.Data{TempTxIndex,4}, ...
                        "Antenna", Temp_anty,...
                        "AntennaHeight", app.UITable.Data{TempTxIndex,7},...
                        "TransmitterPower",10^((app.UITable.Data{TempTxIndex,5}-30-21)/10), ...
                        "TransmitterFrequency",1e9);
                        % the unit of TransmitterPower for txsite is Watt (240620)
                        % -21 is a calibration factor for the gap between the path loss model of experiment and the longley-rice model from experiments 
                        % refer to CoverageTest240523.m

                        coverage(txCoverage, ...
                            'PropagationModel',pmEstimated, ...
                            'ReceiverGain',rxGainEstimated, ...
                            'ReceiverAntennaHeight',AntHeightforCoverage, ...
                            'MaxRange',200000,...
                            'ColorLimits',[-137, -80],...
                            'SignalStrengths',-140:5:-90)
                        end
                    end
                       % This converage is only for SF12, Receiver Ant hegight 6 m, Transmitter power 20 dBm 
                        %% Coverage function end        


                app.AnalyzeButton.Text = 'Analyze';
                app.AnalyzeButton.BackgroundColor = 'w';
                app.AnalyzeButton.Interruptible = 'on';

        end

        % Value changed function: CarrierFrequencyMHzEditField
        function CarrierFrequencyMHzEditFieldValueChanged(app, event)
            value = app.CarrierFrequencyMHzEditField.Value;
            app.CalculateButton.BackgroundColor = 'y';
        end

        % Value changed function: BandwidthkHzDropDown
        function BandwidthkHzDropDownValueChanged(app, event)
            value = app.BandwidthkHzDropDown.Value;
            app.CalculateButton.BackgroundColor = 'y';
        end

        % Value changed function: CodeRateDropDown
        function CodeRateDropDownValueChanged(app, event)
            value = app.CodeRateDropDown.Value;
            app.CalculateButton.BackgroundColor = 'y';
        end

        % Value changed function: HeaderDropDown
        function HeaderDropDownValueChanged(app, event)
            value = app.HeaderDropDown.Value;
            app.CalculateButton.BackgroundColor = 'y';
        end

        % Value changed function: CRCDropDown
        function CRCDropDownValueChanged(app, event)
            value = app.CRCDropDown.Value;
            app.CalculateButton.BackgroundColor = 'y';
        end

        % Value changed function: DataOptimizationDropDown
        function DataOptimizationDropDownValueChanged(app, event)
            value = app.DataOptimizationDropDown.Value;
            app.CalculateButton.BackgroundColor = 'y';
        end

        % Value changed function: SpreadingFactorDropDown
        function SpreadingFactorDropDownValueChanged(app, event)
            value = app.SpreadingFactorDropDown.Value;
            app.CalculateButton.BackgroundColor = 'y';
        end

        % Button pushed function: ImportSetupButton
        function ImportSetupButtonPushed(app, event)
            T = uiimport('-file');
            if isempty(T)
            else
                [Row, Col]=size(T.textdata);
                app.UITable.Data(1:Row-1,1:2) = T.textdata(2:Row,1:2);
                app.UITable.Data{1:Row-1,3:Col} = T.data;
                %app.UITable.Data(2:Row,1) = T.txtdata(2:Row,1)
                app.NumberofRelayEditField.Value = Row -3;
                NumberofRelayEditFieldValueChanged(app,event);
            end
        end

        % Button pushed function: SaveSetupandResultsButton
        function SaveSetupandResultsButtonPushed(app, event)
            SimTime = datetime('now','TimeZone','local','Format','d_MM_y_HH_mm_ss');
            SimTime_str = string(SimTime);
            filename_setup = 'LoRaSetupfile_%s.xlsx';
            filename_setup = sprintf(filename_setup,SimTime_str);
            filename_setup1 = 'PathlossSetup_%s.xlsx';
            filename_setup1 = sprintf(filename_setup1,SimTime_str);
            filename_result = 'LoRaCoverageAnalysis_%s.xls';
            filename_result = sprintf(filename_result,SimTime_str);


            LogDistExponnetValue=[app.LogDistanceexponentEditField.Value];
            PathlossIntercept =[app.LogDistanceInterceptEditField.Value];
            PathlossSetuptable = table(LogDistExponnetValue,PathlossIntercept);

            writetable(app.UITable.Data,filename_setup);
            writetable(PathlossSetuptable,filename_setup1);
            writetable(app.UITable2.Data,filename_result);
        end

        % Callback function: UITable, UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
            app.AnalyzeButton.BackgroundColor = 'y';
        end

        % Value changed function: LogDistanceexponentEditField
        function LogDistanceexponentEditFieldValueChanged(app, event)
            value = app.LogDistanceexponentEditField.Value;
            app.AnalyzeButton.BackgroundColor = 'y';
        end

        % Value changed function: LogDistanceInterceptEditField
        function LogDistanceInterceptEditFieldValueChanged(app, event)
            value = app.LogDistanceInterceptEditField.Value;
            app.AnalyzeButton.BackgroundColor = 'y';
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            selection = app.UITable.Selection;
            
        end

        % Value changed function: RcvAntHeightmEditField
        function RcvAntHeightmEditFieldValueChanged(app, event)
            value = app.RcvAntHeightmEditField.Value;
            app.AnalyzeButton.BackgroundColor = 'y';
        end

        % Value changed function: onCheckBox
        function onCheckBoxValueChanged(app, event)
            value = app.onCheckBox.Value;
            app.AnalyzeButton.BackgroundColor = 'y';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WaveformTab = uitab(app.TabGroup);
            app.WaveformTab.Title = 'Waveform';
            app.UIAxes = uiaxes(app.WaveformTab);
            title(app.UIAxes, 'BER curve')
            xlabel(app.UIAxes, 'SNR')
            ylabel(app.UIAxes, 'BER')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-35 0];
            app.UIAxes.YLim = [1e-06 1];
            app.UIAxes.YScale = 'log';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.CalculateButton = uibutton(app.WaveformTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.BitRatebpsEditFieldLabel = uilabel(app.WaveformTab);
            app.BitRatebpsEditFieldLabel.Text = 'Bit Rate (bps)';
            app.BitRatebpsEditField = uieditfield(app.WaveformTab, 'numeric');
            app.BitRatebpsEditField.Editable = 'off';
            app.SensitivitydBm6dBNFEditFieldLabel = uilabel(app.WaveformTab);
            app.SensitivitydBm6dBNFEditFieldLabel.Text = 'Sensitivity (dBm) @ 6dB NF';
            app.SensitivitydBm6dBNFEditField = uieditfield(app.WaveformTab, 'numeric');
            app.SensitivitydBm6dBNFEditField.Editable = 'off';
            app.SymbolRatespsEditFieldLabel = uilabel(app.WaveformTab);
            app.SymbolRatespsEditFieldLabel.Text = 'Symbol Rate (sps)';
            app.SymbolRatespsEditField = uieditfield(app.WaveformTab, 'numeric');
            app.SymbolRatespsEditField.Editable = 'off';
            app.ChipRatekcpsEditFieldLabel = uilabel(app.WaveformTab);
            app.ChipRatekcpsEditFieldLabel.Text = 'Chip Rate (kcps)';
            app.ChipRatekcpsEditField = uieditfield(app.WaveformTab, 'numeric');
            app.ChipRatekcpsEditField.Editable = 'off';
            app.TimeOnAirmsEditFieldLabel = uilabel(app.WaveformTab);
            app.TimeOnAirmsEditFieldLabel.Text = 'Time On Air (ms)';
            app.TimeOnAirmsEditField = uieditfield(app.WaveformTab, 'numeric');
            app.TimeOnAirmsEditField.Editable = 'off';
            app.RequiredEbNodB105EditFieldLabel = uilabel(app.WaveformTab);
            app.RequiredEbNodB105EditFieldLabel.Text = 'Required Eb/No (dB) @ 10-5';
            app.RequiredEbNodB105EditField = uieditfield(app.WaveformTab, 'numeric');
            app.RequiredEbNodB105EditField.Editable = 'off';
            app.RequiredSNRdB105EditFieldLabel = uilabel(app.WaveformTab);
            app.RequiredSNRdB105EditFieldLabel.Text = 'Required SNR (dB) @ 10-5';
            app.RequiredSNRdB105EditField = uieditfield(app.WaveformTab, 'numeric');
            app.RequiredSNRdB105EditField.Editable = 'off';
            app.LoRaWaveformFactorsPanel = uipanel(app.WaveformTab);
            app.LoRaWaveformFactorsPanel.Title = 'LoRa Waveform Factors';
            app.CarrierFrequencyMHzEditFieldLabel = uilabel(app.LoRaWaveformFactorsPanel);
            app.CarrierFrequencyMHzEditFieldLabel.Text = 'Carrier Frequency (MHz)';
            app.CarrierFrequencyMHzEditField = uieditfield(app.LoRaWaveformFactorsPanel, 'numeric');
            app.CarrierFrequencyMHzEditField.Limits = [0 Inf];
            app.CarrierFrequencyMHzEditField.ValueChangedFcn = createCallbackFcn(app, @CarrierFrequencyMHzEditFieldValueChanged, true);
            app.CarrierFrequencyMHzEditField.Tag = '915';
            app.CarrierFrequencyMHzEditField.Value = 915;
            app.CodeRateDropDownLabel = uilabel(app.LoRaWaveformFactorsPanel);
            app.CodeRateDropDownLabel.Text = 'Code Rate';
            app.CodeRateDropDown = uidropdown(app.LoRaWaveformFactorsPanel);
            app.CodeRateDropDown.Items = {'4/5', '4/6', '4/7', '4/8'};
            app.CodeRateDropDown.ValueChangedFcn = createCallbackFcn(app, @CodeRateDropDownValueChanged, true);
            app.CodeRateDropDown.Value = '4/5';
            app.HeaderDropDownLabel = uilabel(app.LoRaWaveformFactorsPanel);
            app.HeaderDropDownLabel.Text = 'Header';
            app.HeaderDropDown = uidropdown(app.LoRaWaveformFactorsPanel);
            app.HeaderDropDown.Items = {'On', 'Off'};
            app.HeaderDropDown.ValueChangedFcn = createCallbackFcn(app, @HeaderDropDownValueChanged, true);
            app.HeaderDropDown.Value = 'On';
            app.CRCDropDownLabel = uilabel(app.LoRaWaveformFactorsPanel);
            app.CRCDropDownLabel.Text = 'CRC';
            app.CRCDropDown = uidropdown(app.LoRaWaveformFactorsPanel);
            app.CRCDropDown.Items = {'On', 'Off'};
            app.CRCDropDown.ValueChangedFcn = createCallbackFcn(app, @CRCDropDownValueChanged, true);
            app.CRCDropDown.Value = 'On';
            app.BandwidthkHzDropDownLabel = uilabel(app.LoRaWaveformFactorsPanel);
            app.BandwidthkHzDropDownLabel.Text = 'Bandwidth (kHz)';
            app.BandwidthkHzDropDown = uidropdown(app.LoRaWaveformFactorsPanel);
            app.BandwidthkHzDropDown.Items = {'125', '250', '500'};
            app.BandwidthkHzDropDown.ValueChangedFcn = createCallbackFcn(app, @BandwidthkHzDropDownValueChanged, true);
            app.BandwidthkHzDropDown.Value = '125';
            app.SpreadingFactorDropDownLabel = uilabel(app.LoRaWaveformFactorsPanel);
            app.SpreadingFactorDropDownLabel.Text = 'Spreading Factor';
            app.SpreadingFactorDropDown = uidropdown(app.LoRaWaveformFactorsPanel);
            app.SpreadingFactorDropDown.Items = {'5', '6', '7', '8', '9', '10', '11', '12'};
            app.SpreadingFactorDropDown.ValueChangedFcn = createCallbackFcn(app, @SpreadingFactorDropDownValueChanged, true);
            app.SpreadingFactorDropDown.Value = '7';
            app.DataOptimizationDropDownLabel = uilabel(app.LoRaWaveformFactorsPanel);
            app.DataOptimizationDropDownLabel.Text = 'Data Optimization';
            app.DataOptimizationDropDown = uidropdown(app.LoRaWaveformFactorsPanel);
            app.DataOptimizationDropDown.Items = {'On', 'Off'};
            app.DataOptimizationDropDown.ValueChangedFcn = createCallbackFcn(app, @DataOptimizationDropDownValueChanged, true);
            app.DataOptimizationDropDown.Value = 'Off';
            app.MessageEditFieldLabel = uilabel(app.LoRaWaveformFactorsPanel);
            app.MessageEditFieldLabel.Text = 'Message';
            app.MessageEditField = uieditfield(app.LoRaWaveformFactorsPanel, 'text');
            app.MessageEditField.ValueChangingFcn = createCallbackFcn(app, @MessageEditFieldValueChanging, true);
            app.MessageEditField.Value = '1234567890';
            app.MessageLengthByteEditFieldLabel = uilabel(app.LoRaWaveformFactorsPanel);
            app.MessageLengthByteEditFieldLabel.Text = 'Message Length (Byte)';
            app.MessageLengthByteEditField = uieditfield(app.LoRaWaveformFactorsPanel, 'numeric');
            app.MessageLengthByteEditField.Limits = [0 Inf];
            app.MessageLengthByteEditField.Tag = '915';
            app.MessageLengthByteEditField.Editable = 'off';
            app.MessageLengthByteEditField.Value = 10;
            app.CoverageTab = uitab(app.TabGroup);
            app.CoverageTab.Title = 'Coverage';
            app.SetupSitePanel = uipanel(app.CoverageTab);
            app.SetupSitePanel.Title = 'Setup Site';
            app.UITable = uitable(app.SetupSitePanel);
            app.UITable.ColumnName = {'Site'; 'Name'; 'Latitude'; 'Longitude'; 'Tx Power (dBm)'; 'Lsys (dB)'; 'Ant Height (m)'; 'Ant Gain (dB)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true true true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.NumberofRelayEditFieldLabel = uilabel(app.SetupSitePanel);
            app.NumberofRelayEditFieldLabel.Text = 'Number of Relay';
            app.NumberofRelayEditField = uieditfield(app.SetupSitePanel, 'numeric');
            app.NumberofRelayEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofRelayEditFieldValueChanged, true);
            app.SensitivitydBm6dBNFEditField_2Label = uilabel(app.SetupSitePanel);
            app.SensitivitydBm6dBNFEditField_2Label.Text = 'Sensitivity (dBm) @ 6dB NF';
            app.SensitivitydBm6dBNFEditField_2 = uieditfield(app.SetupSitePanel, 'text');
            app.SensitivitydBm6dBNFEditField_2.Editable = 'off';
            app.SensitivitydBm6dBNFEditField_2.Value = 'N/A';
            app.ResultsPanel = uipanel(app.CoverageTab);
            app.ResultsPanel.Title = 'Results';
            app.UITable2 = uitable(app.ResultsPanel);
            app.UITable2.ColumnName = {'Region'; 'Distance(mile)'; 'RSSI (dBm)'; 'LOS'; 'Status'};
            app.UITable2.RowName = {};
            app.AnalyzeButton = uibutton(app.CoverageTab, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.SaveSetupandResultsButton = uibutton(app.CoverageTab, 'push');
            app.SaveSetupandResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSetupandResultsButtonPushed, true);
            app.SaveSetupandResultsButton.Text = 'Save Setup and Results';
            app.ImportSetupButton = uibutton(app.CoverageTab, 'push');
            app.ImportSetupButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSetupButtonPushed, true);
            app.ImportSetupButton.Text = 'Import Setup';
            app.CoveragePanel = uipanel(app.CoverageTab);
            app.CoveragePanel.Title = 'Coverage';
            app.onCheckBox = uicheckbox(app.CoveragePanel);
            app.onCheckBox.ValueChangedFcn = createCallbackFcn(app, @onCheckBoxValueChanged, true);
            app.onCheckBox.Text = 'on';
            app.RcvAntHeightmEditFieldLabel = uilabel(app.CoverageTab);
            app.RcvAntHeightmEditFieldLabel.Text = 'Rcv Ant Height (m)';
            app.RcvAntHeightmEditField = uieditfield(app.CoverageTab, 'numeric');
            app.RcvAntHeightmEditField.ValueChangedFcn = createCallbackFcn(app, @RcvAntHeightmEditFieldValueChanged, true);
            app.RcvAntHeightmEditField.Value = 6;
            app.PathlossPanel = uipanel(app.CoverageTab);
            app.PathlossPanel.Title = 'Path loss';
            app.LogDistanceexponentEditFieldLabel = uilabel(app.PathlossPanel);
            app.LogDistanceexponentEditFieldLabel.Text = 'LogDistance exponent';
            app.LogDistanceexponentEditField = uieditfield(app.PathlossPanel, 'numeric');
            app.LogDistanceexponentEditField.ValueChangedFcn = createCallbackFcn(app, @LogDistanceexponentEditFieldValueChanged, true);
            app.LogDistanceexponentEditField.Value = 1.83;
            app.LogDistanceInterceptEditFieldLabel = uilabel(app.PathlossPanel);
            app.LogDistanceInterceptEditFieldLabel.Text = 'LogDistance Intercept';
            app.LogDistanceInterceptEditField = uieditfield(app.PathlossPanel, 'numeric');
            app.LogDistanceInterceptEditField.ValueChangedFcn = createCallbackFcn(app, @LogDistanceInterceptEditFieldValueChanged, true);
            app.LogDistanceInterceptEditField.Value = 112.9;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoRa_waveform_Gui_v5_240709

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end